classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main1                   matlab.ui.Figure
        Label                   matlab.ui.control.Label
        CONTROLLABILLITYButton  matlab.ui.control.Button
        IDENTIFIABILITYButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        oldpath % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oldpath = pwd;            
            
            rutafinal = app.oldpath(end-12:end);
            comparar = strcmp(rutafinal,'\STRIKE-GOLDD');
            
            optsMes = struct('WindowStyle','modal',... 
              'Interpreter','tex');
            if comparar == 0
                uiwait(warndlg([ '\fontsize{10} You should run the app from \it STRIKE-GOLDD \rm folder. Press OK ' ...
                    'to close the APP and to change the Current folder'],'Error in path',optsMes));
                
                delete(app.SG);
                return
            end
            
            
            addpath(genpath('functions'));
            app.Label.Text = sprintf('Which type of analysis do you want\n to run with STRIKE GOLDD?');

        end

        % Button pushed function: CONTROLLABILLITYButton
        function CONTROLLABILLITYButtonPushed(app, event)
            nombreDeLaClaseApp = 'NLControllability_APP';
            rutaApp = 'C:\Users\xabor\Documents\GitHub\strike-goldd\STRIKE-GOLDD\functions\NLControllability_APP.mlapp';
            if ~contains(path, fileparts(rutaApp))
                addpath(fileparts(rutaApp));
            end
            eval(nombreDeLaClaseApp);
        end

        % Button pushed function: IDENTIFIABILITYButton
        function IDENTIFIABILITYButtonPushed(app, event)
            nombreDeLaClaseApp = 'STRIKE_GOLDD_APP';
            rutaApp = 'C:\Users\xabor\Documents\GitHub\strike-goldd\STRIKE-GOLDD\functions\STRIKE_GOLDD_APP.mlapp';
            if ~contains(path, fileparts(rutaApp))
                addpath(fileparts(rutaApp));
            end
            eval(nombreDeLaClaseApp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main1 = uifigure('Visible', 'off');
            app.Main1.Name = 'MATLAB App';
            app.IDENTIFIABILITYButton = uibutton(app.Main1, 'push');
            app.IDENTIFIABILITYButton.ButtonPushedFcn = createCallbackFcn(app, @IDENTIFIABILITYButtonPushed, true);
            app.IDENTIFIABILITYButton.Text = 'IDENTIFIABILITY';
            app.CONTROLLABILLITYButton = uibutton(app.Main1, 'push');
            app.CONTROLLABILLITYButton.ButtonPushedFcn = createCallbackFcn(app, @CONTROLLABILLITYButtonPushed, true);
            app.CONTROLLABILLITYButton.Text = 'CONTROLLABILLITY';
            app.Label = uilabel(app.Main1);
            % Show the figure after all components are created
            app.Main1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main1)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main1)
        end
    end
end